classdef EventDamageCosts < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        DamageCostsofWeatherEventsLabel  matlab.ui.control.Label
        WeatherEventDetailsPanel       matlab.ui.container.Panel
        LocationUSStateDropDownLabel   matlab.ui.control.Label
        LocationUSStateDropDown        matlab.ui.control.DropDown
        StormdurationhoursSliderLabel  matlab.ui.control.Label
        StormdurationhoursSlider       matlab.ui.control.Slider
        WeatherEventTypeDropDownLabel  matlab.ui.control.Label
        WeatherEventTypeDropDown       matlab.ui.control.DropDown
        DateDatePickerLabel            matlab.ui.control.Label
        DateDatePicker                 matlab.ui.control.DatePicker
        WeatherEventResultsPanel       matlab.ui.container.Panel
        PredicteddamagecostLabel       matlab.ui.control.Label
        PredicteddamagecostValue       matlab.ui.control.EditField
        AxesPanel                      matlab.ui.container.Panel
    end

    
    properties (Access = private)
        Model 
        Data
        testData
        ax
        predictedDamage
    end
    
    methods (Access = private)
        
        function predictCost(app)
            % Use machine learning to predict cost  
            % Include the following statements when compiling the web app using results from the 
            % Regression Learner app (the compiler doesn't always
            % recognize, so be explicit)
            
            %#function classreg.learning.regr.CompactRegressionEnsemble
            %#function classreg.learning.regr.CompactRegressionTree
            %#function mlearnapp.internal.model.DatasetSpecification
            %#function mlearnapp.internal.model.coremodel.TrainedRegressionEnsemble
            %#function mlearnapp.internal.model.transformation.TrainedManualFeatureSelection

            % Get data inputs and convert to format for machine learning
            app.testData = table;
            app.testData.weathercats = categorical(string(app.WeatherEventTypeDropDown.Value));
            app.testData.state = categorical(string(app.LocationUSStateDropDown.Value));
            app.testData.storm_duration = app.StormdurationhoursSlider.Value;
            app.testData.Time = app.DateDatePicker.Value;
            [app.testData.yy,app.testData.MM,app.testData.dd] = ymd(app.testData.Time);
            % Make prediction
            app.predictedDamage = app.Model.predictFcn(app.testData); 
        end
        
        function updateViz(app)
            app.PredicteddamagecostValue.Value = disp_currency(app,app.predictedDamage*1000);
        end
        
        function plotGeoDamage(app)
            subData = app.Data;
            subData = subData(subData.event_type == app.testData.weathercats & ...
                subData.storm_duration > 0 & ...
                subData.state == app.testData.state,:);
            subData.weathercats = removecats(subData.weathercats);
            subData.damage_total = subData.damage_total * 1000;
            ax2 = app.AxesPanel;
            geobubble(ax2,subData,"begin_lat","begin_lon","SizeVariable","damage_total","ColorVariable","weathercats");
        end
    
        function updateALL(app)
            d = uiprogressdlg(app.UIFigure,'Title','Please wait','Message','Updating...');
            
            predictCost(app)
            updateViz(app)
            plotGeoDamage(app)
            
            close(d);
        end
        
        function str = disp_currency(app,amt)   %#ok       
            str = fliplr(sprintf('%10.2f',abs(amt)));
            str = regexprep(str,' ','');
            str = str(sort([1:length(str) 7:3:length(str)]));
            str(7:4:length(str)) = ',';
            str = ['$' fliplr(str)];
            if amt<0
                str = ['(' str ')'];
            end  
        end
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % App to explore events and predicted damage costs
            %
            % Copyright 2018 - 2020 The MathWorks, Inc.
            
            % Load machine learning model and a sample of recent data
            x = load('MLModel.mat');
            app.Model = x.trainedModel;
            y = load('prepEvents.mat');
            app.Data = y.data;
            
            app.LocationUSStateDropDown.Items = y.states.State;
            app.LocationUSStateDropDown.Value = "TEXAS";
            
            % Picking the top 5 categories by frequency of occurence and with significant Geo data
            list = sortrows(y.eventTypes,'Counts','descend');
            nanCount = zeros(20,1);
            for ii = 1:20
                nanCount(ii,1) = sum(isnan(app.Data.begin_lat(app.Data.event_type == list.Type(ii))))... %#ok
                    /sum(app.Data.event_type == list.Type(ii));
            end
            listType = list(nanCount < 0.25,:);
            
            app.WeatherEventTypeDropDown.Items = listType.Type(1:5);
            
            app.DateDatePicker.Value = datetime("now");

            predictCost(app)
            updateViz(app)
            plotGeoDamage(app)
            
        end

        % Value changed function: LocationUSStateDropDown
        function LocationUSStateDropDownValueChanged(app, event)
            updateALL(app)
        end

        % Value changed function: StormdurationhoursSlider
        function StormdurationhoursSliderValueChanged(app, event)
            updateALL(app)
        end

        % Value changed function: WeatherEventTypeDropDown
        function WeatherEventTypeDropDownValueChanged(app, event)
            updateALL(app)
        end

        % Value changed function: DateDatePicker
        function DateDatePickerValueChanged(app, event)
           updateALL(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.DamageCostsofWeatherEventsLabel = uilabel(app.UIFigure);
            app.DamageCostsofWeatherEventsLabel.Text = 'Damage Costs of Weather Events';
            app.WeatherEventDetailsPanel = uipanel(app.UIFigure);
            app.WeatherEventDetailsPanel.Title = 'Weather Event Details';
            app.LocationUSStateDropDownLabel = uilabel(app.WeatherEventDetailsPanel);
            app.LocationUSStateDropDownLabel.Text = 'Location (US State)';
            app.LocationUSStateDropDown = uidropdown(app.WeatherEventDetailsPanel);
            app.LocationUSStateDropDown.ValueChangedFcn = createCallbackFcn(app, @LocationUSStateDropDownValueChanged, true);
            app.StormdurationhoursSliderLabel = uilabel(app.WeatherEventDetailsPanel);
            app.StormdurationhoursSliderLabel.Text = 'Storm duration (hours)';
            app.StormdurationhoursSlider = uislider(app.WeatherEventDetailsPanel);
            app.StormdurationhoursSlider.ValueChangedFcn = createCallbackFcn(app, @StormdurationhoursSliderValueChanged, true);
            app.WeatherEventTypeDropDownLabel = uilabel(app.WeatherEventDetailsPanel);
            app.WeatherEventTypeDropDownLabel.Text = 'Weather Event Type';
            app.WeatherEventTypeDropDown = uidropdown(app.WeatherEventDetailsPanel);
            app.WeatherEventTypeDropDown.Items = {'Option1', 'Option2', 'Option3', 'Option4'};
            app.WeatherEventTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @WeatherEventTypeDropDownValueChanged, true);
            app.WeatherEventTypeDropDown.Value = 'Option1';
            app.DateDatePickerLabel = uilabel(app.WeatherEventDetailsPanel);
            app.DateDatePickerLabel.Text = 'Date';
            app.DateDatePicker = uidatepicker(app.WeatherEventDetailsPanel);
            app.DateDatePicker.DisplayFormat = 'dd-MMM';
            app.DateDatePicker.ValueChangedFcn = createCallbackFcn(app, @DateDatePickerValueChanged, true);
            app.DateDatePicker.Value = datetime([2018 10 29]);
            app.WeatherEventResultsPanel = uipanel(app.UIFigure);
            app.WeatherEventResultsPanel.Title = '  Weather Event Results:';
            app.PredicteddamagecostLabel = uilabel(app.WeatherEventResultsPanel);
            app.PredicteddamagecostLabel.Text = 'Predicted damage cost:';
            app.PredicteddamagecostValue = uieditfield(app.WeatherEventResultsPanel, 'text');
            app.PredicteddamagecostValue.Value = '$-';
            app.AxesPanel = uipanel(app.WeatherEventResultsPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EventDamageCosts

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end